classdef VirtualSensorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VirtualSensorAppUIFigure    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        ThisappallowsuserstotrainanAIbasedvirtualsensortoestimatethetrueairspeedofanaircraftFollowtheworkflowusingthetabsintheappLabel  matlab.ui.control.Label
        VirtualSensorAppLabel       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ImportTab                   matlab.ui.container.Tab
        VariablesListBox            matlab.ui.control.ListBox
        TablevariablesListBoxLabel  matlab.ui.control.Label
        ImportdataButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ModelTab                    matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        EditField                   matlab.ui.control.EditField
        UITable                     matlab.ui.control.Table
        TrainModelButton            matlab.ui.control.Button
        PredictTab                  matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        SelecttestdataButton        matlab.ui.control.Button
        ModelstatusLabel            matlab.ui.control.Label
        PredictButton               matlab.ui.control.Button
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % data read from the user-defined file
        responseColor = [0.25,0.32,0.54];
        predictorColor = [0.31,0.53,0.23];
        notUsedColor = [0.47,0.24,0.24];
        loadedModel
        trainedModel
        prediction
        predictors = {'FuelQuantity', 'OilPressure', 'OilTemperature',...
                      'Altitude', 'ExhaustTemperature', 'FuelFlow',...
                      'FanSpeed', 'WindDirection', 'WindSpeed'}; % used by the model
        testData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try 
                app.loadedModel = load("Model\model.mat");
                app.ModelstatusLabel.Text = "Pretrained model loaded";
            catch
            end
        end

        % Button pushed function: ImportdataButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx','*.csv'},'Choose file');
            fpath = fullfile(path,file);
            if ~isempty(file)
                try
                    app.data = importFlightData(fpath);
                    app.data = app.data(app.data.TrueAirSpeed > 10,:);
                    vars = app.data.Properties.VariableNames;
                    app.VariablesListBox.Items = vars(2:end);
                catch me
                    uialert(app.VirtualSensorAppUIFigure,"Error: " + me.message,"Error")
                end
                app.TrainModelButton.Enable = "on";
            end
        end

        % Value changed function: VariablesListBox
        function VariablesListBoxValueChanged(app, event)
            value = app.VariablesListBox.Value;
            plot(app.UIAxes,app.data.Time,app.data{:,value})
            xlabel(app.UIAxes,"Time");
            ylabel(app.UIAxes,value);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if selectedTab.Title == "Model"

                if ~isempty(app.data)
                    app.UITable.Data = app.data.Properties.VariableNames(2:end)';


                    rowColors = repmat(app.notUsedColor, size(app.UITable.Data, 1), 1);
                    app.UITable.Data(:,2) = cellstr(repmat("Not used",12,1));
                    for i = 1:length(app.UITable.Data)
                        if ismember(app.UITable.Data{i}, app.predictors)
                            rowColors(i,:) = app.predictorColor;
                            app.UITable.Data{i,2} = 'Predictor';
                        elseif ismember(app.UITable.Data{i}, 'TrueAirSpeed')
                            rowColors(i,:) = app.responseColor;
                            app.UITable.Data{i,2} = 'Response';
                        end
                    end
                    app.UITable.BackgroundColor = rowColors;
                end
            elseif selectedTab.Title == "Predict"
                if isempty(app.trainedModel)
                    app.ModelstatusLabel.Text = "Pretrained model loaded";
                else
                    app.ModelstatusLabel.Text = "Trained model loaded";
                end
            end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            if ~isempty(app.data)
                try
                    app.EditField.BackgroundColor = [0.47,0.24,0.24];
                    cleanedData = cleanOilSensors(app.data);
                    app.EditField.Visible = "on";
                    app.EditField.Value = 'Training model...';
                    pause(0.1)
                    app.trainedModel = trainTASModel(cleanedData);
                    app.EditField.Value = 'Model training finished';
                    app.EditField.BackgroundColor = [0.31,0.53,0.23];
                catch me
                    uialert("Error: " + me.message)
                end
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if ~isempty(app.trainedModel)
                model = app.trainedModel;
            else
                model = app.loadedModel;
                app.TextArea.Value = class(model);
                %model = model.mdl
            end
            try
                app.prediction = model.predictFcn(cleanOilSensors(app.testData));
                hold(app.UIAxes2,'on')
                cleanData = cleanOilSensors(app.testData);
                plot(app.UIAxes2,cleanData.Time,cleanData.TrueAirSpeed)
                plot(app.UIAxes2,cleanData.Time,app.prediction)
                hold(app.UIAxes2,"off")
                legend(app.UIAxes2,"Actual","Predicted")
                plot(app.UIAxes2_2,cleanData.Time, cleanData.TrueAirSpeed - app.prediction)
                legend(app.UIAxes2_2,"Residual")
            catch me
                uialert(app.VirtualSensorAppUIFigure,"Something went wrong when predicting response","Error")
            end
        end

        % Callback function
        function PasteButtonPushed(app, event)
            
            data = paste;
            if ~isempty(data)
                app.UITable.Data = data;
            end
        end

        % Button pushed function: SelecttestdataButton
        function SelecttestdataButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx','*.csv'},'Choose file');
            fpath = fullfile(path,file);
            if ~isempty(file)
                try
                    app.testData = importFlightData(fpath);
                    app.testData = app.testData(app.testData.TrueAirSpeed > 10,:);
                    app.Label.Text = "Test data OK";
                catch me
                    uialert("Error: " + me.message)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VirtualSensorAppUIFigure = uifigure('Visible', 'off');
            app.VirtualSensorAppUIFigure.Name = 'Virtual Sensor App';
            app.TabGroup = uitabgroup(app.VirtualSensorAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.UIAxes = uiaxes(app.ImportTab);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.ImportdataButton = uibutton(app.ImportTab, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.TablevariablesListBoxLabel = uilabel(app.ImportTab);
            app.TablevariablesListBoxLabel.Text = 'Table variables';
            app.VariablesListBox = uilistbox(app.ImportTab);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.ValueChangedFcn = createCallbackFcn(app, @VariablesListBoxValueChanged, true);
            app.VariablesListBox.Value = {};
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.GridLayout = uigridlayout(app.ModelTab);
            app.TrainModelButton = uibutton(app.GridLayout, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Variables'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Visible = 'off';
            app.PredictTab = uitab(app.TabGroup);
            app.PredictTab.Title = 'Predict';
            app.UIAxes2 = uiaxes(app.PredictTab);
            title(app.UIAxes2, 'Response')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.PredictTab);
            title(app.UIAxes2_2, 'Residual')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.PredictButton = uibutton(app.PredictTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ModelstatusLabel = uilabel(app.PredictTab);
            app.ModelstatusLabel.Text = 'Model status: ';
            app.SelecttestdataButton = uibutton(app.PredictTab, 'push');
            app.SelecttestdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttestdataButtonPushed, true);
            app.SelecttestdataButton.Text = 'Select test data';
            app.Label = uilabel(app.PredictTab);
            app.Label.Text = '';
            app.VirtualSensorAppLabel = uilabel(app.VirtualSensorAppUIFigure);
            app.VirtualSensorAppLabel.Text = 'Virtual Sensor App';
            app.ThisappallowsuserstotrainanAIbasedvirtualsensortoestimatethetrueairspeedofanaircraftFollowtheworkflowusingthetabsintheappLabel = uilabel(app.VirtualSensorAppUIFigure);
            app.ThisappallowsuserstotrainanAIbasedvirtualsensortoestimatethetrueairspeedofanaircraftFollowtheworkflowusingthetabsintheappLabel.Text = 'This app allows users to train an AI -based virtual sensor to estimate the true airspeed of an aircraft. Follow the workflow using the tabs in the app.';
            app.Image = uiimage(app.VirtualSensorAppUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'airplane.png');
            % Show the figure after all components are created
            app.VirtualSensorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VirtualSensorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VirtualSensorAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VirtualSensorAppUIFigure)
        end
    end
end